classdef BaiTap < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        NghimTab                      matlab.ui.container.Tab
        KtquLabel                     matlab.ui.control.Label
        PtrinhLap                     matlab.ui.control.EditField
        PhngtrnhlpEditFieldLabel      matlab.ui.control.Label
        PhuongPhap0                   matlab.ui.control.DropDown
        PhngphpDropDownLabel          matlab.ui.control.Label
        PhanliB                       matlab.ui.control.NumericEditField
        Nghiem                        matlab.ui.control.Button
        SoLanLap                      matlab.ui.control.NumericEditField
        SlnlpEditFieldLabel           matlab.ui.control.Label
        Saiso                         matlab.ui.control.NumericEditField
        SaischophpEditFieldLabel      matlab.ui.control.Label
        PhanliA                       matlab.ui.control.NumericEditField
        KhongphnliEditFieldLabel      matlab.ui.control.Label
        PTrinh                        matlab.ui.control.EditField
        PhngtrnhEditFieldLabel        matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        NisuyTab                      matlab.ui.container.Tab
        NoiSuy                        matlab.ui.control.Button
        KQ_NS                         matlab.ui.control.EditField
        KtquathcnisuyEditFieldLabel   matlab.ui.control.Label
        kqEditField                   matlab.ui.control.NumericEditField
        KtqunisuyEditFieldLabel       matlab.ui.control.Label
        gt_ns                         matlab.ui.control.NumericEditField
        NhpgitrcnnisuyEditFieldLabel  matlab.ui.control.Label
        Chonpp                        matlab.ui.control.DropDown
        ChnphngphpDropDownLabel       matlab.ui.control.Label
        y                             matlab.ui.control.EditField
        NhpdliuyEditField_2Label      matlab.ui.control.Label
        x                             matlab.ui.control.EditField
        NhpdliuxEditField_2Label      matlab.ui.control.Label
        UIAxes_2                      matlab.ui.control.UIAxes
        HiQuyTab                      matlab.ui.container.Tab
        KetQuaDuDoan                  matlab.ui.control.EditField
        KtqudonLabel                  matlab.ui.control.Label
        PhuongTrinhHoiQuy             matlab.ui.control.EditField
        KetquaLabel                   matlab.ui.control.Label
        NhapY                         matlab.ui.control.EditField
        NhpdliuYLabel                 matlab.ui.control.Label
        NhapX                         matlab.ui.control.EditField
        NhpdliuXLabel                 matlab.ui.control.Label
        Giatridudoan                  matlab.ui.control.NumericEditField
        HoiQuy                        matlab.ui.control.Button
        GitrcndonLabel                matlab.ui.control.Label
        PPHoiQuy                      matlab.ui.control.DropDown
        PhngphpLabel                  matlab.ui.control.Label
        UIAxes2                       matlab.ui.control.UIAxes
        oHmTab                        matlab.ui.container.Tab
        NhapHEditField                matlab.ui.control.NumericEditField
        NhpbchEditFieldLabel          matlab.ui.control.Label
        NhapFxEditField               matlab.ui.control.EditField
        NhphmsEditFieldLabel_3        matlab.ui.control.Label
        Ketqua2EditField              matlab.ui.control.EditField
        KtqutheoxvyLabel_2            matlab.ui.control.Label
        NhapyEditField                matlab.ui.control.EditField
        NhpdliuyLabel                 matlab.ui.control.Label
        NhapxEditField                matlab.ui.control.EditField
        NhpdliuxLabel                 matlab.ui.control.Label
        SaisoDropDown                 matlab.ui.control.DropDown
        ChngitrsaisLabel              matlab.ui.control.Label
        PhuongphapDropDown            matlab.ui.control.DropDown
        PhngphpDropDownLabel_3        matlab.ui.control.Label
        NhapgiatriEditField           matlab.ui.control.NumericEditField
        NhpgitrcntnhEditFieldLabel    matlab.ui.control.Label
        Ketqua1EditField              matlab.ui.control.EditField
        KtqutheoxvyLabel              matlab.ui.control.Label
        HoacLabel                     matlab.ui.control.Label
        DaoHamDayhehe                 matlab.ui.control.Button
        TchphnTab                     matlab.ui.container.Tab
        HocLabel                      matlab.ui.control.Label
        KetquatichphanXYLabel         matlab.ui.control.Label
        NhpdliuYEditField             matlab.ui.control.EditField
        NhpdliuYEditFieldLabel        matlab.ui.control.Label
        NhpdliuXEditField             matlab.ui.control.EditField
        NhpdliuXEditFieldLabel        matlab.ui.control.Label
        TichPhan                      matlab.ui.control.Button
        NhapCanB                      matlab.ui.control.NumericEditField
        KetquaTichPhanLabel           matlab.ui.control.Label
        NhapN                         matlab.ui.control.NumericEditField
        NhpnEditFieldLabel            matlab.ui.control.Label
        NhapCanA                      matlab.ui.control.NumericEditField
        NhpcntnhtchphnEditFieldLabel  matlab.ui.control.Label
        PhuongPhapTichPhan            matlab.ui.control.DropDown
        PhngphpDropDownLabel_2        matlab.ui.control.Label
        NhapHamSoTichPhan             matlab.ui.control.EditField
        NhphmsEditFieldLabel_2        matlab.ui.control.Label
        GiithiunhmTab                 matlab.ui.container.Tab
        Label                         matlab.ui.control.Label
        BOCOTHCHNHMNTHCHNHPHNGPHPTNHLpTV_CLC4Label  matlab.ui.control.Label
        Image                         matlab.ui.control.Image
    end

    
    methods (Access = private)
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: Nghiem, PhuongPhap0
        function NghiemPushed(app, event)
            switch app.PhuongPhap0.Value
                case 'Chia đôi'
                    cla(app.UIAxes,"reset");
                    fx = app.PTrinh.Value;
                    a  = app.PhanliA.Value;
                    b  = app.PhanliB.Value;
                    saiso = app.Saiso.Value;
                    [ketqua,solanlap] = PPChiaDoi(fx, a, b, saiso);
                    app.KtquLabel.Text = num2str(ketqua);
                    app.SoLanLap.Value = double(solanlap);
                    x  = a:0.01:b;
                    y = arrayfun(@(x) eval(fx), x);
                    plot(app.UIAxes,x,y);
                case 'Newton'
                    cla(app.UIAxes,"reset");
                    fx = app.PTrinh.Value;
                    a  = app.PhanliA.Value;
                    b  = app.PhanliB.Value;
                    saiso = app.Saiso.Value;
                    [ketqua,solanlap] = PPNewton(fx, a, b, saiso);
                    app.KtquLabel.Text = num2str(ketqua);
                    app.SoLanLap.Value = double(solanlap);
                    x  = a:0.01:b;
                    y = arrayfun(@(x) eval(fx), x);
                    plot(app.UIAxes,x,y);
                case 'Lặp'
                    cla(app.UIAxes,"reset");
                    fx = app.PTrinh.Value;
                    gx  = app.PtrinhLap.Value;
                    a = app.PhanliA.Value;
                    b  = app.PhanliB.Value;
                    saiso = app.Saiso.Value;
                    [ketqua,solanlap] = PPLapDon(fx,gx,a,b,saiso);
                    app.KtquLabel.Text = num2str(ketqua);
                    app.SoLanLap.Value = double(solanlap);
                    x  = a:0.01:b;
                    y = arrayfun(@(x) eval(gx), x);
                    z = x;
                    plot(app.UIAxes,x,y,'b--',x,z,'r-');
            end
        end

        % Callback function
        function DaoHamPushed(app, event)
           fx = app.NhapFxEditField.Value;
            x = app.NhapgiatriEditField.Value;
            pp = app.PhuongphapDropDown.Value;
            h = app.NhapHEditField.Value;
            ss = app.SaisoDropDown.Value;
            dx = DaoHam(fx,x,h,pp,ss);
            app.Ketqua1EditField.Value = num2str(dx);
            
            xi = app.NhapxEditField.Value;
            yi = app.NhapyEditField.Value;
            dxy = DaoHamXY(xi,yi,x,pp,ss);
            app.Ketqua2EditField.Value = num2str(dxy);
        end

        % Callback function: HoiQuy, PPHoiQuy
        function HoiQuyPushed(app, event)
            switch app.PPHoiQuy.Value
                case 'Tuyến tính'
                    x1 = app.NhapX.Value;
                    x = str2num(x1);
                    y1 = app.NhapY.Value;
                    y = str2num(y1);
                    x_predict = app.Giatridudoan.Value;
                    [a0,a1,y_predict] = PPHoiQuyTuyenTinh(x,y,x_predict);
                    y_fit = a0 + a1 * x;
                    print = ['y = ' num2str(a1) 'x + ' num2str(a0)];
                    plot(app.UIAxes2, x, y , 'go', x, y_fit, 'r-', 'LineWidth', 1);   
                    app.KetQuaDuDoan.Value = num2str(y_predict);
                    app.PhuongTrinhHoiQuy.Value = print;
                case 'Logarit'
                    x1 = app.NhapX.Value;
                    x = str2num(x1);
                    y1 = app.NhapY.Value;
                    y = str2num(y1);
                    x_predict = app.Giatridudoan.Value;
                    [a0,a1,y_predict] = PPHoiQuyLogarit(x,y,x_predict);
                    y_fit = 10^(a0)*x.^(a1);
                    print = ['y = ' num2str(exp(a0)) 'x^(' num2str(a1) ')'];
                    plot(app.UIAxes2, x, y , 'go', x, y_fit, 'r-', 'LineWidth', 1);         
                    app.KetQuaDuDoan.Value = num2str(y_predict);
                    app.PhuongTrinhHoiQuy.Value = print;
                case 'Mũ e'
                    x1 = app.NhapX.Value;
                    x = str2num(x1);
                    y1 = app.NhapY.Value;
                    y = str2num(y1);
                    x_predict = app.Giatridudoan.Value;
                    [a0,a1,y_predict] = PPHoiQuyMuE(x,y,x_predict);
                    y_fit = exp(a0 + a1 * x);
                    print = ['y = ' num2str(exp(a0)) 'e^(' num2str(a1) 'x)'];
                    plot(app.UIAxes2, x, y , 'go', x, y_fit, 'r-', 'LineWidth', 1);
                    app.KetQuaDuDoan.Value = num2str(y_predict);
                    app.PhuongTrinhHoiQuy.Value = print;
            end

        end

        % Callback function: PhuongPhapTichPhan, TichPhan
        function TichPhanButtonPushed(app, event)

            switch app.PhuongPhapTichPhan.Value
                case 'Hình Thang'
                    %TP bang fx
                    fx = app.NhapHamSoTichPhan.Value;
                    a  = app.NhapCanA.Value;
                    b  = app.NhapCanB.Value;
                    N  = app.NhapN.Value;
                    y  = TichPhanHinhThang(fx,a,b,N);
                    app.KetquaTichPhanLabel.Text   = num2str(y);


                   %TP bang mang X Y
                    x_input = app.NhpdliuXEditField.Value;
                    x_array = strsplit(x_input, ' ');
                    x1 = str2double(x_array)
                    y_input = app.NhpdliuYEditField.Value;
                    y_array = strsplit(y_input, ' ');
                    y1 = str2double(y_array)
                    kq = TichPhanHinhThangXY(x1, y1, a, b)
                    kq_1 = num2str(kq);
                    app.KetquatichphanXYLabel.Text = kq_1;
                case 'Simpson 1/3'
                    fx = app.NhapHamSoTichPhan.Value;
                    a= app.NhapCanA.Value;
                    b=app.NhapCanB.Value;
                    N=app.NhapN.Value;
                    y = Simpsonmotba(fx,a,b,N);
                    app.KetquaTichPhanLabel.Text = num2str(y);


                    %TP Simpson XY
                    x_input = app.NhpdliuXEditField.Value;
                    x_array = strsplit(x_input, ' ');
                    x1 = str2double(x_array)
                    y_input = app.NhpdliuYEditField.Value;
                    y_array = strsplit(y_input, ' ');
                    y1 = str2double(y_array)
                    kq = TichPhanSimpson13XY(x1, y1, a, b)
                    kq_1 = num2str(kq);
                    app.KetquatichphanXYLabel.Text = kq_1;
                case 'Simpson 3/8'
                    fx = app.NhapHamSoTichPhan.Value;
                    a= app.NhapCanA.Value;
                    b=app.NhapCanB.Value;
                    N=app.NhapN.Value;
                    y = Simpsonbatam(fx,a,b,N);
                    app.KetquaTichPhanLabel.Text = num2str(y);

                    %TP Simpson 3/8 
                    x_input = app.NhpdliuXEditField.Value;
                    x_array = strsplit(x_input, ' ');
                    x1 = str2double(x_array)
                    y_input = app.NhpdliuYEditField.Value;
                    y_array = strsplit(y_input, ' ');
                    y1 = str2double(y_array)
                    kq = TichPhanSimpson83XY(x1, y1, a, b)
                    kq_1 = num2str(kq);
                    app.KetquatichphanXYLabel.Text = kq_1;

            end
        end

        % Callback function: Chonpp, NoiSuy
        function NoiSuyPushed(app, event)
            switch app.Chonpp.Value
                case 'Newton'
                    xa = str2num(app.x.Value);
                    ya = str2num(app.y.Value);
                    x = app.gt_ns.Value;
                    result = NS_NEWTON(xa, ya, x);
                    app.kqEditField.Value = double(result);
                    xPlot = linspace(min(xa), max(xa), 100);
                    yPlot = NS_NEWTON(xa, ya, xPlot);
                    plot(app.UIAxes_2, xPlot, yPlot);
                    hold(app.UIAxes_2, 'on');
                    scatter(app.UIAxes_2, xa, ya, 'filled');
                    hold(app.UIAxes_2, 'off');
                    p = polyfit(xa, ya, length(xa) - 1);
                    app.KQ_NS.Value = poly2str(p, 'x');
                case 'Lagrange'
                    xa = app.x.Value;
                    ya = app.y.Value;
                    x = app.gt_ns.Value;
                    xa = str2num(xa);
                    ya = str2num(ya);
                    result = NS_LAGRANGE(xa, ya, x);
                    app.kqEditField.Value = double(result);
                    xPlot = linspace(min(xa), max(xa), 100);
                    yPlot = NS_LAGRANGE(xa, ya, xPlot);
                    plot(app.UIAxes_2, xPlot, yPlot);
                    hold(app.UIAxes_2, 'on');
                    scatter(app.UIAxes_2, xa, ya, 'filled');
                    hold(app.UIAxes_2, 'off');
                    p = polyfit(xa, ya, length(xa) - 1);
                    app.KQ_NS.Value = poly2str(p, 'x');
            end
        end

        % Callback function: DaoHamDayhehe, PhuongphapDropDown, SaisoDropDown
        function DaoHamDayheheButtonPushed(app, event)
            fx = app.NhapFxEditField.Value;
            x = app.NhapgiatriEditField.Value;
            pp = app.PhuongphapDropDown.Value;
            h = app.NhapHEditField.Value;
            ss = app.SaisoDropDown.Value;
            dx = DaoHam(fx,x,h,pp,ss);
            app.Ketqua1EditField.Value = num2str(dx);

            xi = app.NhapxEditField.Value;
            yi = app.NhapyEditField.Value;
            dxy = DaoHamXY(xi,yi,x,pp,ss);
            app.Ketqua2EditField.Value = num2str(dxy);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NghimTab = uitab(app.TabGroup);
            app.NghimTab.Title = 'Nghiệm';
            app.UIAxes = uiaxes(app.NghimTab);
            title(app.UIAxes, 'Đồ thị hàm số Tìm Nghiệm')
            zlabel(app.UIAxes, 'Z')
            app.PhngtrnhEditFieldLabel = uilabel(app.NghimTab);
            app.PhngtrnhEditFieldLabel.Text = 'Phương trình';
            app.PTrinh = uieditfield(app.NghimTab, 'text');
            app.PTrinh.Value = 'x - sin(x) + 0.5';
            app.KhongphnliEditFieldLabel = uilabel(app.NghimTab);
            app.KhongphnliEditFieldLabel.Text = 'Khoảng phân li';
            app.PhanliA = uieditfield(app.NghimTab, 'numeric');
            app.PhanliA.Value = -1;
            app.SaischophpEditFieldLabel = uilabel(app.NghimTab);
            app.SaischophpEditFieldLabel.Text = 'Sai số cho phép';
            app.Saiso = uieditfield(app.NghimTab, 'numeric');
            app.Saiso.Value = 0.005;
            app.SlnlpEditFieldLabel = uilabel(app.NghimTab);
            app.SlnlpEditFieldLabel.Text = 'Số lần lặp';
            app.SoLanLap = uieditfield(app.NghimTab, 'numeric');
            app.Nghiem = uibutton(app.NghimTab, 'push');
            app.Nghiem.ButtonPushedFcn = createCallbackFcn(app, @NghiemPushed, true);
            app.Nghiem.Text = 'Kết quả nghiệm';
            app.PhanliB = uieditfield(app.NghimTab, 'numeric');
            app.PhanliB.Value = 2;
            app.PhngphpDropDownLabel = uilabel(app.NghimTab);
            app.PhngphpDropDownLabel.Text = 'Phương pháp';
            app.PhuongPhap0 = uidropdown(app.NghimTab);
            app.PhuongPhap0.Items = {'Chia đôi', 'Lặp', 'Newton'};
            app.PhuongPhap0.ValueChangedFcn = createCallbackFcn(app, @NghiemPushed, true);
            app.PhuongPhap0.Value = 'Newton';
            app.PhngtrnhlpEditFieldLabel = uilabel(app.NghimTab);
            app.PhngtrnhlpEditFieldLabel.Text = 'Phương trình lặp';
            app.PtrinhLap = uieditfield(app.NghimTab, 'text');
            app.PtrinhLap.Value = 'sin(x) - 0.5';
            app.KtquLabel = uilabel(app.NghimTab);
            app.KtquLabel.Text = 'Kết quả';
            app.NisuyTab = uitab(app.TabGroup);
            app.NisuyTab.Title = 'Nội suy';
            app.UIAxes_2 = uiaxes(app.NisuyTab);
            title(app.UIAxes_2, 'Đồ thị hàm số Nội Suy')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.NhpdliuxEditField_2Label = uilabel(app.NisuyTab);
            app.NhpdliuxEditField_2Label.Text = 'Nhập dữ liệu x ';
            app.x = uieditfield(app.NisuyTab, 'text');
            app.x.Value = '1 2 3 4';
            app.NhpdliuyEditField_2Label = uilabel(app.NisuyTab);
            app.NhpdliuyEditField_2Label.Text = 'Nhập dữ liệu y';
            app.y = uieditfield(app.NisuyTab, 'text');
            app.y.Value = '2 1 6 3';
            app.ChnphngphpDropDownLabel = uilabel(app.NisuyTab);
            app.ChnphngphpDropDownLabel.Text = 'Chọn phương pháp';
            app.Chonpp = uidropdown(app.NisuyTab);
            app.Chonpp.Items = {'Newton', 'Lagrange'};
            app.Chonpp.ValueChangedFcn = createCallbackFcn(app, @NoiSuyPushed, true);
            app.Chonpp.Value = 'Lagrange';
            app.NhpgitrcnnisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.NhpgitrcnnisuyEditFieldLabel.Text = 'Nhập giá trị cần nội suy';
            app.gt_ns = uieditfield(app.NisuyTab, 'numeric');
            app.gt_ns.Value = 2.3;
            app.KtqunisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.KtqunisuyEditFieldLabel.Text = 'Kết quả nội suy ';
            app.kqEditField = uieditfield(app.NisuyTab, 'numeric');
            app.KtquathcnisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.KtquathcnisuyEditFieldLabel.Text = 'Kết quả đa thức nội suy ';
            app.KQ_NS = uieditfield(app.NisuyTab, 'text');
            app.NoiSuy = uibutton(app.NisuyTab, 'push');
            app.NoiSuy.ButtonPushedFcn = createCallbackFcn(app, @NoiSuyPushed, true);
            app.NoiSuy.Text = 'Kết Quả Nội Suy';
            app.HiQuyTab = uitab(app.TabGroup);
            app.HiQuyTab.Title = 'Hồi Quy';
            app.UIAxes2 = uiaxes(app.HiQuyTab);
            title(app.UIAxes2, 'Đồ thị hàm số Hồi Quy')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.PhngphpLabel = uilabel(app.HiQuyTab);
            app.PhngphpLabel.Text = 'Phương pháp';
            app.PPHoiQuy = uidropdown(app.HiQuyTab);
            app.PPHoiQuy.Items = {'Tuyến tính', 'Logarit', 'Mũ e'};
            app.PPHoiQuy.ValueChangedFcn = createCallbackFcn(app, @HoiQuyPushed, true);
            app.PPHoiQuy.Value = 'Tuyến tính';
            app.GitrcndonLabel = uilabel(app.HiQuyTab);
            app.GitrcndonLabel.Text = 'Giá trị cần dự đoán';
            app.HoiQuy = uibutton(app.HiQuyTab, 'push');
            app.HoiQuy.ButtonPushedFcn = createCallbackFcn(app, @HoiQuyPushed, true);
            app.HoiQuy.Text = 'Kết Quả Hồi Quy';
            app.Giatridudoan = uieditfield(app.HiQuyTab, 'numeric');
            app.Giatridudoan.Value = 3;
            app.NhpdliuXLabel = uilabel(app.HiQuyTab);
            app.NhpdliuXLabel.Text = 'Nhập dữ liệu X';
            app.NhapX = uieditfield(app.HiQuyTab, 'text');
            app.NhapX.Value = '1 2 3 4 5 6 7';
            app.NhpdliuYLabel = uilabel(app.HiQuyTab);
            app.NhpdliuYLabel.Text = 'Nhập dữ liệu Y';
            app.NhapY = uieditfield(app.HiQuyTab, 'text');
            app.NhapY.Value = '2 3 4 5 6 7 8';
            app.KetquaLabel = uilabel(app.HiQuyTab);
            app.KetquaLabel.Text = 'Kết quả ';
            app.PhuongTrinhHoiQuy = uieditfield(app.HiQuyTab, 'text');
            app.KtqudonLabel = uilabel(app.HiQuyTab);
            app.KtqudonLabel.Text = 'Kết quả dự đoán';
            app.KetQuaDuDoan = uieditfield(app.HiQuyTab, 'text');
            app.oHmTab = uitab(app.TabGroup);
            app.oHmTab.Title = 'Đạo Hàm';
            app.DaoHamDayhehe = uibutton(app.oHmTab, 'push');
            app.DaoHamDayhehe.ButtonPushedFcn = createCallbackFcn(app, @DaoHamDayheheButtonPushed, true);
            app.DaoHamDayhehe.Text = 'Kết Quả Đạo Hàm';
            app.HoacLabel = uilabel(app.oHmTab);
            app.HoacLabel.Text = 'Hoặc';
            app.KtqutheoxvyLabel = uilabel(app.oHmTab);
            app.KtqutheoxvyLabel.Text = 'Kết quả theo fx và h  ';
            app.Ketqua1EditField = uieditfield(app.oHmTab, 'text');
            app.NhpgitrcntnhEditFieldLabel = uilabel(app.oHmTab);
            app.NhpgitrcntnhEditFieldLabel.Text = 'Nhập giá trị cần tính ';
            app.NhapgiatriEditField = uieditfield(app.oHmTab, 'numeric');
            app.NhapgiatriEditField.Value = 4;
            app.PhngphpDropDownLabel_3 = uilabel(app.oHmTab);
            app.PhngphpDropDownLabel_3.Text = 'Phương pháp';
            app.PhuongphapDropDown = uidropdown(app.oHmTab);
            app.PhuongphapDropDown.Items = {'Tien', 'Lui', 'TrungTam'};
            app.PhuongphapDropDown.ValueChangedFcn = createCallbackFcn(app, @DaoHamDayheheButtonPushed, true);
            app.PhuongphapDropDown.Value = 'Tien';
            app.ChngitrsaisLabel = uilabel(app.oHmTab);
            app.ChngitrsaisLabel.Text = 'Chọn giá trị sai số';
            app.SaisoDropDown = uidropdown(app.oHmTab);
            app.SaisoDropDown.Items = {'O(h)', 'O(h^2)'};
            app.SaisoDropDown.ValueChangedFcn = createCallbackFcn(app, @DaoHamDayheheButtonPushed, true);
            app.SaisoDropDown.Value = 'O(h)';
            app.NhpdliuxLabel = uilabel(app.oHmTab);
            app.NhpdliuxLabel.Text = 'Nhập dữ liệu x ';
            app.NhapxEditField = uieditfield(app.oHmTab, 'text');
            app.NhapxEditField.Value = '4 5 7 9 5 13 ';
            app.NhpdliuyLabel = uilabel(app.oHmTab);
            app.NhpdliuyLabel.Text = 'Nhập dữ liệu y ';
            app.NhapyEditField = uieditfield(app.oHmTab, 'text');
            app.NhapyEditField.Value = '5 7 9 5 2 12';
            app.KtqutheoxvyLabel_2 = uilabel(app.oHmTab);
            app.KtqutheoxvyLabel_2.Text = 'Kết quả theo x và y ';
            app.Ketqua2EditField = uieditfield(app.oHmTab, 'text');
            app.NhphmsEditFieldLabel_3 = uilabel(app.oHmTab);
            app.NhphmsEditFieldLabel_3.Text = 'Nhập hàm số ';
            app.NhapFxEditField = uieditfield(app.oHmTab, 'text');
            app.NhapFxEditField.Value = '3*sin(3*x)';
            app.NhpbchEditFieldLabel = uilabel(app.oHmTab);
            app.NhpbchEditFieldLabel.Text = 'Nhập bước h';
            app.NhapHEditField = uieditfield(app.oHmTab, 'numeric');
            app.NhapHEditField.Value = 0.2;
            app.TchphnTab = uitab(app.TabGroup);
            app.TchphnTab.Title = 'Tích phân';
            app.NhphmsEditFieldLabel_2 = uilabel(app.TchphnTab);
            app.NhphmsEditFieldLabel_2.Text = 'Nhập hàm số';
            app.NhapHamSoTichPhan = uieditfield(app.TchphnTab, 'text');
            app.NhapHamSoTichPhan.Value = 'x*sin(x)';
            app.PhngphpDropDownLabel_2 = uilabel(app.TchphnTab);
            app.PhngphpDropDownLabel_2.Text = 'Phương pháp';
            app.PhuongPhapTichPhan = uidropdown(app.TchphnTab);
            app.PhuongPhapTichPhan.Items = {'Hình Thang', 'Simpson 1/3', 'Simpson 3/8'};
            app.PhuongPhapTichPhan.ValueChangedFcn = createCallbackFcn(app, @TichPhanButtonPushed, true);
            app.PhuongPhapTichPhan.Value = 'Hình Thang';
            app.NhpcntnhtchphnEditFieldLabel = uilabel(app.TchphnTab);
            app.NhpcntnhtchphnEditFieldLabel.Text = 'Nhập cận để tính tích phân';
            app.NhapCanA = uieditfield(app.TchphnTab, 'numeric');
            app.NhapCanA.Value = 1;
            app.NhpnEditFieldLabel = uilabel(app.TchphnTab);
            app.NhpnEditFieldLabel.Text = 'Nhập n';
            app.NhapN = uieditfield(app.TchphnTab, 'numeric');
            app.NhapN.Value = 10;
            app.KetquaTichPhanLabel = uilabel(app.TchphnTab);
            app.KetquaTichPhanLabel.Text = 'KetquaTichPhan';
            app.NhapCanB = uieditfield(app.TchphnTab, 'numeric');
            app.NhapCanB.Value = 4;
            app.TichPhan = uibutton(app.TchphnTab, 'push');
            app.TichPhan.ButtonPushedFcn = createCallbackFcn(app, @TichPhanButtonPushed, true);
            app.TichPhan.Text = 'Kết Quả';
            app.NhpdliuXEditFieldLabel = uilabel(app.TchphnTab);
            app.NhpdliuXEditFieldLabel.Text = 'Nhập dữ liệu X';
            app.NhpdliuXEditField = uieditfield(app.TchphnTab, 'text');
            app.NhpdliuXEditField.Value = '1 2 4 7 6 5';
            app.NhpdliuYEditFieldLabel = uilabel(app.TchphnTab);
            app.NhpdliuYEditFieldLabel.Text = 'Nhập dữ liệu Y';
            app.NhpdliuYEditField = uieditfield(app.TchphnTab, 'text');
            app.NhpdliuYEditField.Value = '3 7 6 5 5 1';
            app.KetquatichphanXYLabel = uilabel(app.TchphnTab);
            app.KetquatichphanXYLabel.Text = 'KetquatichphanXY';
            app.HocLabel = uilabel(app.TchphnTab);
            app.HocLabel.Text = 'Hoặc';
            app.GiithiunhmTab = uitab(app.TabGroup);
            app.GiithiunhmTab.Title = 'Giới thiệu nhóm';
            app.Image = uiimage(app.GiithiunhmTab);
            app.Image.ImageSource = 'logo-khoa-hoc-tu-nhien-inkythuatso-01-25-16-15-49.jpg';
            app.BOCOTHCHNHMNTHCHNHPHNGPHPTNHLpTV_CLC4Label = uilabel(app.GiithiunhmTab);
            app.BOCOTHCHNHMNTHCHNHPHNGPHPTNHLpTV_CLC4Label.Text = {''; '                     BÁO CÁO THỰC HÀNH'; '               '; ''; '     MÔN: THỰC HÀNH PHƯƠNG PHÁP TÍNH'; ''; ''; 'Lớp: ĐTV_CLC4'; ''};
            app.Label = uilabel(app.GiithiunhmTab);
            app.Label.Text = {'Danh sách thành viên:'; ''; 'Ngô Chơn Quang 	          - 21207085'; 'Bùi Minh Nhựt   	          - 21207070'; 'Phan Trần Anh Duy 	  - 21207026'; 'Lê Nguyễn Xuân Đoàn 	  - 21207xxx'; 'Hoàng Lê Đức Anh   	  - 21207xxx'; 'Huỳnh Lê Trí Cường 	  - 21207028'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BaiTap

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end